classdef lamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Label      matlab.ui.control.Label
        RunButton  matlab.ui.control.StateButton
        LAMPLabel  matlab.ui.control.Label
        Lamp       matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            value = app.RunButton.Value;
            
            if value == 1

                % Turn lamp green immediately
                app.Lamp.Color = "g";
                drawnow;   % force UI update
                app.RunButton.Text = "Running";
                for x = 1:100
                    app.Label.Text = num2str(x);
                    drawnow;       % allow UI refresh
                    pause(0.01);   % control counting speed
                end
            
                % After counting, turn lamp red
                app.RunButton.Text = "Reset";
                app.Lamp.Color = "r";
            else
                app.RunButton.Text = "Run";
                app.Lamp.Color = "r";
                app.Label.Text = "0";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tooltip = {'Color'};
            app.LAMPLabel = uilabel(app.UIFigure);
            app.LAMPLabel.Text = 'LAMP';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end