classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Gauge                     matlab.ui.control.Gauge
        ValueChangingField        matlab.ui.control.EditField
        Slider                    matlab.ui.control.Slider
        ChangeEditField           matlab.ui.control.EditField
        ChangeEditFieldLabel      matlab.ui.control.Label
        PreviousEditField         matlab.ui.control.NumericEditField
        PreviousEditFieldLabel    matlab.ui.control.Label
        PresentEditField          matlab.ui.control.NumericEditField
        PresentEditFieldLabel     matlab.ui.control.Label
        ChangesusingChangeFunctionLabel  matlab.ui.control.Label
        ValueChangingSlider       matlab.ui.control.Slider
        ValueChangeFunctionLabel  matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        ValueChangedSlider        matlab.ui.control.Slider
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ValueChangedSlider
        function ValueChangedSliderValueChanged(app, event)
            % Value Changed Callback / Function
            app.EditField.Value = num2str(app.ValueChangedSlider.Value);
            
            % Value Changing Callback / Function
        end

        % Value changed function: ValueChangingSlider
        function ValueChangingSliderValueChanged(app, event)
            % Present / New Value 
            new = event.Value;
            app.PresentEditField.Value = new;

            % Previous / Old Value
            prev = event.PreviousValue;
            app.PreviousEditField.Value = prev;

            % Change
            if(new > prev)
                app.ChangeEditField.Value = "Increased";
            else
                app.ChangeEditField.Value = "Decreased";
            end
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.ValueChangingField.Value = num2str(event.Value);
            app.Gauge.Value = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ValueChangedSlider = uislider(app.UIFigure);
            app.ValueChangedSlider.Limits = [0 5];
            app.ValueChangedSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChangedSliderValueChanged, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '0';
            app.ValueChangeFunctionLabel = uilabel(app.UIFigure);
            app.ValueChangeFunctionLabel.Text = 'Value Change Function';
            app.ValueChangingSlider = uislider(app.UIFigure);
            app.ValueChangingSlider.ValueChangedFcn = createCallbackFcn(app, @ValueChangingSliderValueChanged, true);
            app.ChangesusingChangeFunctionLabel = uilabel(app.UIFigure);
            app.ChangesusingChangeFunctionLabel.Text = 'Changes using Change Function';
            app.PresentEditFieldLabel = uilabel(app.UIFigure);
            app.PresentEditFieldLabel.Text = 'Present';
            app.PresentEditField = uieditfield(app.UIFigure, 'numeric');
            app.PreviousEditFieldLabel = uilabel(app.UIFigure);
            app.PreviousEditFieldLabel.Text = 'Previous';
            app.PreviousEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChangeEditFieldLabel = uilabel(app.UIFigure);
            app.ChangeEditFieldLabel.Text = 'Change';
            app.ChangeEditField = uieditfield(app.UIFigure, 'text');
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ValueChangingField = uieditfield(app.UIFigure, 'text');
            app.Gauge = uigauge(app.UIFigure, 'circular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end